classdef ContracilityAnalysisBeta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MAESTRAContractilityAnalysisLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        JobFileTab                      matlab.ui.container.Tab
        setOutputDirButton              matlab.ui.control.Button
        OutputDirectoryEditField        matlab.ui.control.EditField
        OutputDirectoryEditFieldLabel   matlab.ui.control.Label
        ChangeAliasesButton             matlab.ui.control.Button
        SelectionFilterButton           matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        LoadJobFileButton               matlab.ui.control.Button
        NewJobFileButton                matlab.ui.control.Button
        PIVConfigurationTab             matlab.ui.container.Tab
        PIVexecutionButtonGroup         matlab.ui.container.ButtonGroup
        augumentRunButton               matlab.ui.control.RadioButton
        FreshRunButton                  matlab.ui.control.RadioButton
        OtherPanel                      matlab.ui.container.Panel
        UseFeatureTrackingCheckBox      matlab.ui.control.CheckBox
        CorrectWholeImageDrift          matlab.ui.control.CheckBox
        AcquisitionRate                 matlab.ui.control.EditField
        AcquisitionRateframespersecondLabel  matlab.ui.control.Label
        InterpMethodDropDown            matlab.ui.control.DropDown
        SubpixelInterpolationMethodDropDown_2Label  matlab.ui.control.Label
        RemovePassButton                matlab.ui.control.Button
        AddPassButton                   matlab.ui.control.Button
        BackButton                      matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        DeformationMeasurementsPIVPanel  matlab.ui.container.Panel
        UITablePIV                      matlab.ui.control.Table
        ReferenceSelectionButtonGroup   matlab.ui.container.ButtonGroup
        RefSelFolderDropDown            matlab.ui.control.DropDown
        RefSelDifferentFolder           matlab.ui.control.RadioButton
        RefSelManualNewFileButton       matlab.ui.control.Button
        RefSelManualBrowseButton        matlab.ui.control.Button
        RefSelManualRefEditField        matlab.ui.control.EditField
        RefSelF2FSkipCheckBox           matlab.ui.control.CheckBox
        RefSelF2FNrefEditField          matlab.ui.control.EditField
        referenceframesEditField_2Label  matlab.ui.control.Label
        RefSelPIVSkipCheckBox           matlab.ui.control.CheckBox
        RefSelPIVNrefEditField          matlab.ui.control.EditField
        referenceframesEditFieldLabel   matlab.ui.control.Label
        RefSelPIVWdwSpcEditField        matlab.ui.control.EditField
        WindowSpacingEditFieldLabel     matlab.ui.control.Label
        RefSelPIVWdwSzEditField         matlab.ui.control.EditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        RefSelManualRadioButton         matlab.ui.control.RadioButton
        RefSelF2FRadioButton            matlab.ui.control.RadioButton
        RefSelPIVRadioButton            matlab.ui.control.RadioButton
        TFMandPerformanceTab            matlab.ui.container.Tab
        PIVPassToUse                    matlab.ui.container.Panel
        PIVPassDropDown                 matlab.ui.control.DropDown
        PIVPassDropDownLabel            matlab.ui.control.Label
        ElastographySerranoetal2023     matlab.ui.container.Panel
        DoElasto                        matlab.ui.control.CheckBox
        SingleCellAnalysisButtonGroup   matlab.ui.container.ButtonGroup
        CellPoseModelDropDown           matlab.ui.control.DropDown
        CellPoseModelDropDownLabel      matlab.ui.control.Label
        EnableSingleCell                matlab.ui.control.CheckBox
        MaskFolderDropDown              matlab.ui.control.DropDown
        MaskFromFolder                  matlab.ui.control.RadioButton
        MaskFromVideo                   matlab.ui.control.RadioButton
        BackButton_2                    matlab.ui.control.Button
        NextButton_3                    matlab.ui.control.Button
        PerformanceParametersPanel      matlab.ui.container.Panel
        MaxRAMGBLabel                   matlab.ui.control.Label
        MaxCPUMem                       matlab.ui.control.EditField
        MaxGPUMemEditField              matlab.ui.control.EditField
        MaxGPUMemGBLabel                matlab.ui.control.Label
        GPUAccelerationDropDown         matlab.ui.control.DropDown
        GPUAccelerationDropDownLabel    matlab.ui.control.Label
        ContractileForcemoduleTFMPanel  matlab.ui.container.Panel
        DoTFM                           matlab.ui.control.CheckBox
        GelStiffness                    matlab.ui.control.EditField
        GelYoungsModuluskPaLabel        matlab.ui.control.Label
        GelThickness                    matlab.ui.control.EditField
        GelThicknessmLabel_2            matlab.ui.control.Label
        CalibrationFactor               matlab.ui.control.EditField
        CalibrationFactormpixelLabel    matlab.ui.control.Label
        RunAnalysisTab                  matlab.ui.container.Tab
        PIVPassDropDown_2               matlab.ui.control.DropDown
        PIVPassDropDown_2Label          matlab.ui.control.Label
        ModuleDropDown                  matlab.ui.control.DropDown
        ModuleDropDownLabel             matlab.ui.control.Label
        WholeFieldAnalysisLabel         matlab.ui.control.Label
        hrsLabel                        matlab.ui.control.Label
        ETA                             matlab.ui.control.EditField
        ETAEditFieldLabel               matlab.ui.control.Label
        ProgressLabel                   matlab.ui.control.Label
        UITableProgress                 matlab.ui.control.Table
        QuitAnalysisButton              matlab.ui.control.Button
        BackButton_3                    matlab.ui.control.Button
        StartAnalysisButton             matlab.ui.control.Button
        UITableStatus                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        SelectionFilterDialogApp % App to the selection filter dialog
        AliasDialogApp % App to the manual alias dialog
        RootFolder % Where images start to be discovered
        QuitDialog % Dialog showed while stopping execution
    end
    
    properties (Access = public)
        chosenLevelsForAlias % Description
        Config % Struct to save configuration info. Will be saved as JSON
        Logfile % Full file path to log
        QuitSignal % Interrupt execution
    end
    
    methods (Access = private)
        function updateButtonsTab1(app)
            if height(app.UITable.Data)==0
                app.SelectAllButton.Enable = 'off';
                app.ClearAllButton.Enable  = 'off';
                app.NextButton.Enable      = 'off';
                app.ChangeAliasesButton.Enable    = 'off';
                app.SelectionFilterButton.Enable  = 'off';
            else
                app.SelectAllButton.Enable  = 'on';
                app.ClearAllButton.Enable   = 'on';
                app.NextButton.Enable      = 'on';
                app.ChangeAliasesButton.Enable      = 'on';
                app.SelectionFilterButton.Enable    = 'on';
            end
        end


         function updateButtonsTab2(app)
            % Check if there is a config file
            testPath = fullfile(app.RootFolder, 'config.json');

            if exist(testPath,'file')
                cfg_data = contUtils.loadJsonConfig(testPath);

                try
                    selectedButton = app.ReferenceSelectionButtonGroup.SelectedObject;

                    switch cfg_data.ReferenceSelection.ReferenceSelectionMode
                        case 1 % PIV 
                            app.RefSelPIVWdwSzEditField.Value = num2str(cfg_data.ReferenceSelection.wdw_size);
                            app.RefSelPIVWdwSpcEditField.Value = num2str(cfg_data.ReferenceSelection.wdw_spacing);
                            app.RefSelPIVSkipCheckBox.Value = cfg_data.ReferenceSelection.auto_skip ;
                            app.RefSelPIVNrefEditField.Value = num2str(cfg_data.ReferenceSelection.Nref_frames);
                        case 2 % Frame to frame 
                            app.RefSelF2FSkipCheckBox.Value = cfg_data.ReferenceSelection.auto_skip;
                            app.RefSelF2FNrefEditField.Value = num2str(cfg_data.ReferenceSelection.Nref_frames);
                        case 3 % Different folder
                            folderName = regexp(cfg_data.ReferenceSelection.ReferenceFolderLocation,'..\\(\w+)','tokens','once');
                            if isempty(folderName) % unix 
                                folderName = regexp(cfg_data.ReferenceSelection.ReferenceFolderLocation,'../(\w+)','tokens','once');
                            end
                            app.RefSelFolderDropDown.Value = folderName;
                        case 0 % Manual
                            app.RefSelManualRefEditField.Value = cfg_data.ReferenceSelection.manualReferenceFile;
                    end

                    % Update Deformation data
                    nPass = length(cfg_data.Deformation);
                    T = app.UITablePIV.Data;
                    for irow = 1 : nPass
                        T.WindowSize(irow) = cfg_data.Deformation(irow).wdw_size;
                        T.WindowSpacing(irow) = cfg_data.Deformation(irow).wdw_spacing;
                    end
                    app.UITablePIV.Data = T;
                    app.InterpMethodDropDown.Value = cfg_data.Deformation(irow).SubPixelInterpolationMode;
                    app.AcquisitionRate.Value = string(cfg_data.OtherParameters.FrameRate);
                    app.CorrectWholeImageDrift.Value = cfg_data.OtherParameters.CorrectImageDrift;
                    app.UseFeatureTrackingCheckBox = cfg_data.OtherParameters.UseFeatureDriftCorrection;
                   
                end
            end
        end


        function updateButtonsTab3(app)
            % Check if there is a config file
            testPath = fullfile(app.RootFolder, 'config.json');
            if exist(testPath,'file')
                cfg_data = contUtils.loadJsonConfig(testPath);
                if cfg_data.TFM.DoTFM
                    app.DoTFM.Value = 1;
                    app.CalibrationFactormpixelLabel.Enable = 'on';
                    app.CalibrationFactor.Enable = 'on';
                    app.GelThicknessmLabel_2.Enable= 'on';
                    app.GelThickness.Enable= 'on';
                    app.GelYoungsModuluskPaLabel.Enable = 'on';
                    app.GelStiffness.Enable = 'on';
                    app.CalibrationFactor.Value = num2str(cfg_data.TFM.CalibrationFactor);
                    app.GelThickness.Value = num2str(cfg_data.TFM.GelThickness);
                    app.GelStiffness.Value = num2str(cfg_data.TFM.GelStiffness);
                end
                if cfg_data.SingleCell.UseCellpose
                    app.EnableSingleCell.Value = 1;

                else
                    app.EnableSingleCell.Value = 0;
                end
            end
        end
        
        function saveJobFile(app)
            T = app.UITable.Data;
            jobfile = T(T.Analyze==1,:);
            jobfile = removevars(jobfile,{'Analyze'});
            fullfilepath = fullfile(app.OutputDirectoryEditField.Value,'jobfile.csv');
            writetable(jobfile,fullfilepath,'Delimiter',',')
        end
        
        function addPass(app,wdw_sz,wdw_spc)
            p = height(app.UITablePIV.Data);
            newRow = table;
            newRow.Pass =  p+1;
            newRow.WindowSize = wdw_sz;
            newRow.WindowSpacing = wdw_spc;
            app.UITablePIV.Data = [app.UITablePIV.Data;newRow];
        end
        
        function updateConfig(app)
            % Reference Selection:
            selectedButton = app.ReferenceSelectionButtonGroup.SelectedObject;
            app.Config.ReferenceSelection.ReferenceSelectionModeText = selectedButton.Text;

            switch selectedButton.Text
                case "Automatic: PIV-based"
                    app.Config.ReferenceSelection.ReferenceSelectionMode = 1;
                    app.Config.ReferenceSelection.wdw_size = str2double(app.RefSelPIVWdwSzEditField.Value);
                    app.Config.ReferenceSelection.wdw_spacing = str2double(app.RefSelPIVWdwSpcEditField.Value);
                    app.Config.ReferenceSelection.Nreps = 1;
                    app.Config.ReferenceSelection.auto_skip = app.RefSelPIVSkipCheckBox.Value;
                    app.Config.ReferenceSelection.Nref_frames = str2double(app.RefSelPIVNrefEditField.Value);
                case "Automatic: Frame to frame difference (recommended)"
                    app.Config.ReferenceSelection.ReferenceSelectionMode = 2;
                    app.Config.ReferenceSelection.auto_skip = app.RefSelF2FSkipCheckBox.Value;
                    app.Config.ReferenceSelection.Nref_frames = str2double(app.RefSelF2FNrefEditField.Value);
                case "The reference frames are in a separate folder"
                    app.Config.ReferenceSelection.ReferenceSelectionMode = 3;
                    app.Config.ReferenceSelection.ReferenceFolderLocation = ['..',filesep,app.RefSelFolderDropDown.Value];

                case "Manual: I'll provide reference frame file (see user guide)"
                    app.Config.ReferenceSelection.ReferenceSelectionMode = 0;
                    app.Config.ReferenceSelection.manualReferenceFile = app.RefSelManualRefEditField.Value;
            end
            app.Config.ReferenceSelection.SubPixelInterpolationMode =  app.InterpMethodDropDown.Value;
            app.Config.ReferenceSelection.resPercent = 0; % For uncertainty quantification

            % Deformation Measurement
            T = app.UITablePIV.Data;
            for irow = 1 : height(T)
                app.Config.Deformation(irow).wdw_size = T.WindowSize(irow);
                app.Config.Deformation(irow).wdw_spacing = T.WindowSpacing(irow);
                app.Config.Deformation(irow).Nreps = 1; % UQ hidden from user, perhaps available in newer release
                app.Config.Deformation(irow).resPercent = 0;
                app.Config.Deformation(irow).SubPixelInterpolationMode =  app.InterpMethodDropDown.Value;
            end
            % Other Parameters
            app.Config.OtherParameters.FrameRate = str2double(app.AcquisitionRate.Value);
            app.Config.OtherParameters.CorrectImageDrift = app.CorrectWholeImageDrift.Value;
            
            %DWS addition: use features for drift correction
            app.Config.OtherParameters.UseFeatureDriftCorrection = app.UseFeatureTrackingCheckBox.Value;

            % TFM Parameters
            app.Config.TFM.DoTFM = app.DoTFM.Value;
            if app.DoTFM.Value
                app.Config.TFM.CalibrationFactor = str2double(app.CalibrationFactor.Value);
                app.Config.TFM.GelThickness = str2double(app.GelThickness.Value);
                app.Config.TFM.GelStiffness = str2double(app.GelStiffness.Value);
            end

            % Performance Parameters
            app.Config.Performance.MaxRAM = str2double(app.MaxCPUMem.Value);
            if strcmp(app.GPUAccelerationDropDown.Value,'enabled')
                app.Config.Performance.UseGPU = true;
                app.Config.Performance.MaxGPUMem = str2double(app.MaxGPUMemEditField.Value);
            else
                app.Config.Performance.UseGPU = false;
            end
            
            % Single Cell Parameters
            app.Config.SingleCell.UseSingleCell = app.EnableSingleCell.Value;
            app.Config.SingleCell.UseCellpose = app.MaskFromVideo.Value;
            app.Config.SingleCell.Directory   = getCellposeDir(app);
%             app.Config.SingleCell.CellposeEnvActivate = "conda activate cellpose";
%             app.Config.SingleCell.CellposePythonCall = "python -m cellpose";
%             app.Config.SingleCell.CellposeModel = "C:\Users\rik_y\.cellpose\models\CP_20220708_225554";
%             app.Config.SingleCell.CellposeOtherArgs = " --diameter 0 --flow_threshold 1 --use_gpu --save_png --save_flows --save_outline --chan 0 --verbose";
            
            app.Config.SingleCell.CellposeModel = fullfile(pwd,'cellpose_model',app.CellPoseModelDropDown.Value);

            % Output to JSON file
            str = jsonencode(app.Config);
            fid = fopen(fullfile(app.OutputDirectoryEditField.Value,'config.json'),'w');
            fprintf(fid,'%s',str);
            fclose(fid);
            
        end
        
        function updateRefSelFieldEnable(app,selection)
            switch selection
                case {"Automatic: PIV-based","1"}
                    % Enable the PIV buttons
                    app.RefSelPIVWdwSzEditField.Enable = 'on';
                    app.RefSelPIVWdwSpcEditField.Enable = 'on';
                    app.RefSelPIVNrefEditField.Enable = 'on';
                    app.RefSelPIVSkipCheckBox.Enable  = 'on';
                    % Disable Frame 2 frame
                    app.RefSelF2FSkipCheckBox.Enable = 'off';
                    app.RefSelF2FNrefEditField.Enable = 'off';
                    % Disable Separate Folder
                    app.RefSelFolderDropDown.Enable = 'off'; 
                    % Disable Manual
                    app.RefSelManualNewFileButton.Enable = 'off';
                    app.RefSelManualBrowseButton.Enable = 'off';
                    app.RefSelManualRefEditField.Enable = 'off';
                    
                case {"Automatic: Frame to frame difference (recommended)","2"}
                    % Disable the PIV buttons
                    app.RefSelPIVWdwSzEditField.Enable = 'off';
                    app.RefSelPIVWdwSpcEditField.Enable = 'off';
                    app.RefSelPIVNrefEditField.Enable = 'off';
                    app.RefSelPIVSkipCheckBox.Enable  = 'off';
                    % Enable Frame 2 frame
                    app.RefSelF2FSkipCheckBox.Enable = 'on';
                    app.RefSelF2FNrefEditField.Enable = 'on';
                    % Disable Separate Folder
                    app.RefSelFolderDropDown.Enable = 'off'; 
                    % Disable Manual
                    app.RefSelManualNewFileButton.Enable = 'off';
                    app.RefSelManualBrowseButton.Enable = 'off';
                    app.RefSelManualRefEditField.Enable = 'off';
                case {"The reference frames are in a separate folder","3"}
                    % Disable the PIV buttons
                    app.RefSelPIVWdwSzEditField.Enable = 'off';
                    app.RefSelPIVWdwSpcEditField.Enable = 'off';
                    app.RefSelPIVNrefEditField.Enable = 'off';
                    app.RefSelPIVSkipCheckBox.Enable  = 'off';
                    % Disable Frame 2 frame
                    app.RefSelF2FSkipCheckBox.Enable = 'off';
                    app.RefSelF2FNrefEditField.Enable = 'off';
                    % Enable Separate Folder
                    app.RefSelFolderDropDown.Enable = 'on'; 
                    % Disable Manual
                    app.RefSelManualNewFileButton.Enable = 'off';
                    app.RefSelManualBrowseButton.Enable = 'off';
                    app.RefSelManualRefEditField.Enable = 'off';
                case {"Manual: I'll provide reference frame file (see user guide)","0"}
                    % Disable the PIV buttons
                    app.RefSelPIVWdwSzEditField.Enable = 'off';
                    app.RefSelPIVWdwSpcEditField.Enable = 'off';
                    app.RefSelPIVNrefEditField.Enable = 'off';
                    app.RefSelPIVSkipCheckBox.Enable  = 'off';
                    % Disable Frame 2 frame
                    app.RefSelF2FSkipCheckBox.Enable = 'off';
                    app.RefSelF2FNrefEditField.Enable = 'off';
                    % Disable Separate Folder
                    app.RefSelFolderDropDown.Enable = 'off'; 
                    % Enable Manual
                    app.RefSelManualNewFileButton.Enable = 'on';
                    app.RefSelManualBrowseButton.Enable = 'on';
                    app.RefSelManualRefEditField.Enable = 'on';
            end
        end
        
        function CPdir = getCellposeDir(app)
            selectedButton = app.SingleCellAnalysisButtonGroup.SelectedObject;
            selection = selectedButton.Text;
            switch selection
                case {"Use an image from the video to obtain cell masks (Cellpose 2)","1"}
                    CPdir = '';
                case {"Images for cell masks are in a separate folder","2"}
                    CPdir = ['..',filesep,app.MaskFolderDropDown.Value];
            end

        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.QuitSignal = false;
            app.Config = contUtils.loadDefaultConfig();

            % Startup of First Tab
            % Example of Jobfile
%             jobpath = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\contractility_run_20220705_133430';
%             jobname = 'jobfile.csv';
%             app.UITable.Data = readJobFile2(jobname, jobpath);
%             app.UITable.Data.Analyze = true([height(app.UITable.Data),1]);
%             updateButtonsTab1(app)
%             app.OutputDirectoryEditField.Value = 'G:\Shared drives\Stanford UCSD_sharedDrive\contractility_analysis_rel_4_0\TaxolFullMovies\contractility_run_20220705_133430';
            %             saveJobFile(app)
            
            % Startup of Second Tab
            addPass(app, 64, 32);
            
            % Startup of Third Tab
            if app.Config.Performance.UseGPU
                gpuInfo = gpuDevice;
                gpuText = [{[gpuInfo.Name]},{['AvailableMemory: ', num2str(gpuInfo.AvailableMemory/2^30), ' GB']}];
                app.GPUAccelerationDropDown.Value = 'enabled';
                app.MaxGPUMemEditField.Enable = 'on';
                app.MaxGPUMemEditField.Value = num2str(app.Config.Performance.MaxGPUMem);
            else
                gpuText = [{'No GPU detected. Analysis will run';};{'using multi-threaded CPU version.'}];
                app.GPUAccelerationDropDown.Value = 'disabled';
                app.GPUAccelerationDropDown.Enable = 'off';
                app.MaxGPUMemEditField.Enable = 'off';
            end
            app.GPUAccelerationDropDown.Tooltip = gpuText;
            % app.MaxCPUMem.Value = num2str(app.Config.Performance.MaxRAM);
            
            % Use Max available RAM by default
            avMem = contUtils.getAvailableRAM;
            app.MaxCPUMem.Value = num2str(avMem);
            app.MaxCPUMem.Tooltip = [{'Max usable RAM : ';};{[num2str(avMem) ' GB']}];

            % For UITableStatus row numbering
            app.UITableStatus.RowName = 'numbered';
            set(app.UITableProgress, 'ColumnName', {}, 'RowName', {});

            %DWS - CellPose choices%%%%
            % cellposemodels = dir(fullfile(pwd,'cellpose_model'));
            cellposemodels = dir( singleCell.getCellposeModelPath );
            app.CellPoseModelDropDown.Items = {cellposemodels(~[cellposemodels.isdir]).name};

        end

        % Button pushed function: NewJobFileButton
        function NewJobFileButtonPushed(app, event)
            app.RootFolder = uigetdir('/');
            % These two lines are to return figure to focus after uigetdir
            drawnow;
            figure(app.UIFigure)
            if ~app.RootFolder
                return
            end
            folder_images = discoverImages2(app.RootFolder,app.UIFigure);
            if ~isempty(folder_images)
                A = cell2table(folder_images,'VariableNames',{'Location'});
                [A.Alias,app.chosenLevelsForAlias] = autoAlias(A.Location);
                A.Analyze = true([height(A),1]);
                % Add data to the Table UI Component
                app.UITable.Data = A;
            else
                uialert(app.UIFigure,'No files to analyze found.','Invalid Directory');
                app.UITable.Data(:,:)=[];
            end
            updateButtonsTab1(app);
            OutputDir = app.RootFolder;
            formatOut = 'yyyymmdd_HHMMSS';
            d = datestr(now,formatOut);
            OutputDir = fullfile(OutputDir,['contractility_run_',d]);
            app.OutputDirectoryEditField.Value = OutputDir;

            % Only fresh run of PIV allowed
            app.augumentRunButton.Enable = 'off';
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.UITable.Data.Analyze = true(height(app.UITable.Data),1);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.UITable.Data.Analyze = false(height(app.UITable.Data),1);
        end

        % Button pushed function: SelectionFilterButton
        function SelectionFilterButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.SelectionFilterDialogApp = SelectionFilterDialog(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            mkdir(app.OutputDirectoryEditField.Value)
            try
                jobtable = app.UITable.Data;
                jobtable = jobtable(jobtable.Analyze==1,:);
                % Check that Aliases are unique
                assert(length(unique(jobtable.Alias)) == height(jobtable),...
                    'One or more aliases are repeated. Please generate unique aliases for each entry.');
                % Check that Output Directory is a valid folder
                assert(isfolder(app.OutputDirectoryEditField.Value),'Invalid Output Directory')
            catch ME
                uialert(app.UIFigure,ME.message,'Error');
                return
            end
            % Save jobfile
            saveJobFile(app)
            % Close potentially leftover open windows
            delete(app.SelectionFilterDialogApp)
            delete(app.AliasDialogApp)
            % Populate the dropdown options of reference frame
            jobtable = app.UITable.Data;
            jobtable = jobtable(jobtable.Analyze==1,:);
            adir = jobtable.Location{1};
            s = regexp(adir, filesep, 'split');
            folderUp = fullfile(s{1:length(s)-1});
            contents = dir(folderUp);
            folders  = contents([contents.isdir]);
            toRemove = strcmp({folders.name},".") |  strcmp({folders.name},"..");
            choices = folders(~toRemove);
            app.RefSelFolderDropDown.Items = {choices.name};
            app.MaskFolderDropDown.Items = {choices.name};
            
            % Move to next Tab
            app.TabGroup.SelectedTab = app.PIVConfigurationTab;
        end

        % Button pushed function: LoadJobFileButton
        function LoadJobFileButtonPushed(app, event)
            [f, p] = uigetfile({'*.csv'},'Pick a jobfile');
            drawnow;
            figure(app.UIFigure)
            if ~f
                return
            end
            A = readJobFile2(f, p);
            A.Analyze = true([height(A),1]);
            app.UITable.Data = A;
            
            app.RootFolder = p;
            % Populate and update the config when loading an existing
            % jobfile
            updateButtonsTab1(app);
            updateButtonsTab2(app);
            updateButtonsTab3(app);
            
            app.OutputDirectoryEditField.Value = p;
            
            % Option to augument PIV run (i.e. more passes, TFM, etc.) 
            app.augumentRunButton.Enable = 'on';


        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            
        end

        % Button pushed function: ChangeAliasesButton
        function ChangeAliasesButtonPushed(app, event)
            app.AliasDialogApp = MakeAliasDialogApp(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Disable tab navigation with the tabgroup
            app.TabGroup.SelectedTab = event.OldValue;
            uialert(app.UIFigure,'Use the Next and Back buttons to navigate the GUI.','Action Not Permitted');
        end

        % Button pushed function: AddPassButton
        function AddPassButtonPushed(app, event)
            
            if height(app.UITablePIV.Data) == 0
                addPass(app,64,32);
            else
                lastPass = app.UITablePIV.Data(end,:); 
                addPass(app, floor(lastPass.WindowSize / 2 ), ...
                                floor(lastPass.WindowSize / 4) );
            end
                
            
        end

        % Button pushed function: RemovePassButton
        function RemovePassButtonPushed(app, event)
            if height(app.UITablePIV.Data)
                app.UITablePIV.Data(end,:)=[];
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            % Add checkpoints
            try
                % Check everything is okay in reference selection
                selectedButton = app.ReferenceSelectionButtonGroup.SelectedObject;
                switch selectedButton.Text
                    case 'Automatic: PIV-based'
                        wdw_sz = str2double(app.RefSelPIVWdwSzEditField.Value);
                        wdw_spc = str2double(app.RefSelPIVWdwSpcEditField.Value);
                        nref = str2double(app.RefSelPIVNrefEditField.Value);
                        assert(contUtils.isNaturalNumber(wdw_sz) ,'PIV window size must be a positive integer.')
                        assert(contUtils.isNaturalNumber(wdw_spc) ,'PIV window spacing must be a positive integer.')
                        assert(contUtils.isNaturalNumber(nref),'Number of reference frames must be a positive integer.')
                    case 'Automatic: Frame to frame difference (recommended)'
                        nref = str2double(app.RefSelF2FNrefEditField.Value);
                        assert(contUtils.isNaturalNumber(nref) ,'Number of reference frames must be a positive integer.')
                    case "Manual: I'll provide reference frame file (see user guide)"
                        % Check that the manual reference frame file is valid by checking matching
                        % Location, Aliases with current jobfile and also reference frame vector is valid
                end
                
                % Check Deformation Measurements (PIV)
                assert(all((app.UITablePIV.Data.WindowSize)),'PIV window size must be a positive integer.');
                assert(all((app.UITablePIV.Data.WindowSpacing)),'PIV window spacing must be a positive integer.');
%                 assert(all(contUtils.(app.UITablePIV.Data.nReps)),'Number of repeats for PIV uncertainty quantification must be a positive integer.');
%                 assert(all(app.UITablePIV.Data.percReplacement>=0),'Percent of replacement for PIV uncertainty quantification must be >= 0.');
                
                % Check 'Other Parameters'
                assert(str2double(app.AcquisitionRate.Value)>0,'Acquisition rate must be a positive number.');
            catch ME
                uialert(app.UIFigure,ME.message,'Error');
                return
            end
            % Update configuration
            updateConfig(app)
            
            % Move to next Tab
            app.TabGroup.SelectedTab = app.TFMandPerformanceTab;
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            % Move to next Tab
            app.TabGroup.SelectedTab = app.JobFileTab;
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.PIVConfigurationTab;
        end

        % Selection changed function: ReferenceSelectionButtonGroup
        function ReferenceSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ReferenceSelectionButtonGroup.SelectedObject;
            updateRefSelFieldEnable(app,selectedButton.Text)
        end

        % Button pushed function: setOutputDirButton
        function setOutputDirButtonPushed(app, event)
            OutputDir = uigetdir(app.RootFolder);
            drawnow;
            figure(app.UIFigure)
            if ~OutputDir
                return
            end
            formatOut = 'yyyymmdd_HHMMSS';
            d = datestr(now,formatOut);
            OutputDir = fullfile(OutputDir,['contractility_run_',d]);
            app.OutputDirectoryEditField.Value = OutputDir;

        end

        % Value changed function: GPUAccelerationDropDown
        function GPUAccelerationDropDownValueChanged(app, event)
            value = app.GPUAccelerationDropDown.Value;
            if strcmp(value,'enabled')
                app.MaxGPUMemEditField.Enable = 'on';
                gDev = gpuDevice;
                app.MaxGPUMemEditField.Value = num2str(round(gDev.AvailableMemory/1e9,1));
            else
                app.MaxGPUMemEditField.Enable = 'off';
            end
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            try
                % Check parameters for TFM
                if app.DoTFM.Value
                    assert(isnumeric(str2double(app.CalibrationFactor.Value)),'Calibration factor must be a number.');
                    assert(isnumeric(str2double(app.GelThickness.Value)),"Gel thickness factor must be a number.");
                    assert(isnumeric(str2double(app.GelStiffness.Value)),"Young's modulus must be a number.");
                end
                % Check performance parameters
                assert(isnumeric(str2double(app.MaxCPUMem.Value)),'Max RAM must be a number.');
                if strcmp(app.GPUAccelerationDropDown.Value,'enabled')
                    assert(isnumeric(str2double(app.MaxGPUMemEditField.Value)),'Max RAM must be a number.');
                end
            catch ME
                uialert(app.UIFigure,ME.message,'Error');
                return
            end
            % Update configuration
            updateConfig(app)
            
            % Move to next Tab
            app.TabGroup.SelectedTab = app.RunAnalysisTab;
            
            % Update status table
            jobtable = app.UITable.Data;
            jobtable = jobtable(jobtable.Analyze==1,:);
            statustable = removevars(jobtable,{'Analyze'});
            statustable.Status(:) = {'Not Started'};
            app.UITableStatus.Data = statustable;
            drawnow
        end

        % Button pushed function: StartAnalysisButton
        function StartAnalysisButtonPushed(app, event)
            app.StartAnalysisButton.Enable = 'off';
            app.BackButton_3.Enable = 'off';
            app.QuitAnalysisButton.Enable = 'on';
            
            % Launch main function
            contractility_analysis_rel_4_0(app);
            
            % If stopped signal was pushed, update buttons
            if app.QuitSignal
                app.StartAnalysisButton.Enable = 'on';
                app.BackButton_3.Enable = 'on';
                app.QuitSignal = 0;
                delete(app.QuitDialog)
            end
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            % Move to previous Tab
            app.TabGroup.SelectedTab = app.TFMandPerformanceTab;
        end

        % Button pushed function: QuitAnalysisButton
        function QuitAnalysisButtonPushed(app, event)
            app.QuitDialog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Stopping execution, please wait.',...
                'Indeterminate','on');
            drawnow
            figure(app.UIFigure)
            app.QuitAnalysisButton.Enable = 'off';
            app.QuitSignal = 1;
        end

        % Value changed function: DoTFM
        function DoTFMValueChanged(app, event)
            value = app.DoTFM.Value;
            if value
                app.CalibrationFactormpixelLabel.Enable = 'on';
                app.CalibrationFactor.Enable = 'on';
                app.GelThicknessmLabel_2.Enable= 'on';
                app.GelThickness.Enable= 'on';
                app.GelYoungsModuluskPaLabel.Enable = 'on';
                app.GelStiffness.Enable = 'on';
            else
                app.CalibrationFactormpixelLabel.Enable = 'off';
                app.CalibrationFactor.Enable = 'off';
                app.GelThicknessmLabel_2.Enable= 'off';
                app.GelThickness.Enable= 'off';
                app.GelYoungsModuluskPaLabel.Enable = 'off';
                app.GelStiffness.Enable = 'off';
            end
        end

        % Button pushed function: RefSelManualNewFileButton
        function RefSelManualNewFileButtonPushed(app, event)
            T = app.UITable.Data;
            jobfile = T(T.Analyze==1,:);
            alias_list = jobfile.Alias;
            default_path = app.OutputDirectoryEditField.Value;
            default_name = 'ref_selection_manual.csv';
            [filename,pathname] = empty_manual_ref_selection(default_path,default_name,alias_list);
            app.RefSelManualRefEditField.Value = fullfile(pathname,filename);
            figure(app.UIFigure)
        end

        % Button pushed function: RefSelManualBrowseButton
        function RefSelManualBrowseButtonPushed(app, event)
            default_path = app.OutputDirectoryEditField.Value;
            [filename, pathname] = uigetfile( {'*.csv'}, 'Pick a reference file', ...
                'MultiSelect', 'off',default_path);
            app.RefSelManualRefEditField.Value = fullfile(pathname,filename);
            figure(app.UIFigure)
        end

        % Selection changed function: SingleCellAnalysisButtonGroup
        function SingleCellAnalysisButtonGroupSelectionChanged(app, event)
            selectedButton = app.SingleCellAnalysisButtonGroup.SelectedObject;
            selection = selectedButton.Text;
            switch selection
                case {"Use an image from the video to obtain cell masks","1"}
                    app.MaskFolderDropDown.Enable = 'off';
                    app.CellPoseModelDropDown.Enable = 'on';
                    app.MandatoryCellposeInputsPanel.Enable = 'on';
                    app.OptionalCellposeArgumentsEditField.Enable = 'on';
                case {"Images for cell masks are in a separate folder","2"}
                    app.MaskFolderDropDown.Enable = 'on';
                    app.CellPoseModelDropDown.Enable = 'off';
                    app.MandatoryCellposeInputsPanel.Enable = 'off';
                    app.OptionalCellposeArgumentsEditField.Enable = 'off';
            end
        end

        % Value changed function: EnableSingleCell
        function EnableSingleCellValueChanged(app, event)
            
            if app.EnableSingleCell.Value

                app.MaskFromVideo.Enable = 'on';
                app.MaskFromFolder.Enable = 'on';
                
                app.MaskFromVideo.Value = true;
            else

                app.MaskFromVideo.Enable = 'off';
                app.MaskFromFolder.Enable = 'off';

            end


        end

        % Value changed function: CorrectWholeImageDrift
        function CorrectWholeImageDriftValueChanged(app, event)
            value = app.CorrectWholeImageDrift.Value;
            if value
                app.UseFeatureTrackingCheckBox.Enable = 'on';
            else
                app.UseFeatureTrackingCheckBox.Enable = 'off';
            end
        end

        % Value changed function: UseFeatureTrackingCheckBox
        function UseFeatureTrackingCheckBoxValueChanged(app, event)
            value = app.UseFeatureTrackingCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Contractility Analysis';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.JobFileTab = uitab(app.TabGroup);
            app.JobFileTab.Title = '1. Job File';
            app.NewJobFileButton = uibutton(app.JobFileTab, 'push');
            app.NewJobFileButton.ButtonPushedFcn = createCallbackFcn(app, @NewJobFileButtonPushed, true);
            app.NewJobFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'file-solid.svg');
            app.NewJobFileButton.Text = 'New Job File';
            app.LoadJobFileButton = uibutton(app.JobFileTab, 'push');
            app.LoadJobFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadJobFileButtonPushed, true);
            app.LoadJobFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder-solid.svg');
            app.LoadJobFileButton.Text = 'Load Job File';
            app.UITable = uitable(app.JobFileTab);
            app.UITable.ColumnName = {'Location'; 'Alias'; 'Analyze'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [false false true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.SelectAllButton = uibutton(app.JobFileTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Icon = fullfile(pathToMLAPP, 'icons', 'square-check-solid.svg');
            app.SelectAllButton.Enable = 'off';
            app.SelectAllButton.Text = 'Select All';
            app.ClearAllButton = uibutton(app.JobFileTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Icon = fullfile(pathToMLAPP, 'icons', 'square-xmark-solid.svg');
            app.ClearAllButton.Enable = 'off';
            app.ClearAllButton.Text = 'Clear All';
            app.NextButton = uibutton(app.JobFileTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.SelectionFilterButton = uibutton(app.JobFileTab, 'push');
            app.SelectionFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectionFilterButtonPushed, true);
            app.SelectionFilterButton.Icon = fullfile(pathToMLAPP, 'icons', 'filter-solid.svg');
            app.SelectionFilterButton.Enable = 'off';
            app.SelectionFilterButton.Text = 'Selection Filter';
            app.ChangeAliasesButton = uibutton(app.JobFileTab, 'push');
            app.ChangeAliasesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeAliasesButtonPushed, true);
            app.ChangeAliasesButton.Icon = fullfile(pathToMLAPP, 'icons', 'pen-to-square-solid.svg');
            app.ChangeAliasesButton.Enable = 'off';
            app.ChangeAliasesButton.Text = 'Change Aliases';
            app.OutputDirectoryEditFieldLabel = uilabel(app.JobFileTab);
            app.OutputDirectoryEditFieldLabel.Text = ' Output Directory:';
            app.OutputDirectoryEditField = uieditfield(app.JobFileTab, 'text');
            app.setOutputDirButton = uibutton(app.JobFileTab, 'push');
            app.setOutputDirButton.ButtonPushedFcn = createCallbackFcn(app, @setOutputDirButtonPushed, true);
            app.setOutputDirButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder-tree-solid.svg');
            app.setOutputDirButton.Text = '';
            app.PIVConfigurationTab = uitab(app.TabGroup);
            app.PIVConfigurationTab.Title = '2. PIV Configuration';
            app.ReferenceSelectionButtonGroup = uibuttongroup(app.PIVConfigurationTab);
            app.ReferenceSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReferenceSelectionButtonGroupSelectionChanged, true);
            app.ReferenceSelectionButtonGroup.Title = 'Reference Selection';
            app.RefSelPIVRadioButton = uiradiobutton(app.ReferenceSelectionButtonGroup);
            app.RefSelPIVRadioButton.Text = 'Automatic: PIV-based';
            app.RefSelF2FRadioButton = uiradiobutton(app.ReferenceSelectionButtonGroup);
            app.RefSelF2FRadioButton.Text = 'Automatic: Frame to frame difference (recommended)';
            app.RefSelF2FRadioButton.Value = true;
            app.RefSelManualRadioButton = uiradiobutton(app.ReferenceSelectionButtonGroup);
            app.RefSelManualRadioButton.Text = 'Manual: I''ll provide reference frame file (see user guide)';
            app.WindowSizeEditFieldLabel = uilabel(app.ReferenceSelectionButtonGroup);
            app.WindowSizeEditFieldLabel.Enable = 'off';
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.RefSelPIVWdwSzEditField = uieditfield(app.ReferenceSelectionButtonGroup, 'text');
            app.RefSelPIVWdwSzEditField.Enable = 'off';
            app.RefSelPIVWdwSzEditField.Value = '128';
            app.WindowSpacingEditFieldLabel = uilabel(app.ReferenceSelectionButtonGroup);
            app.WindowSpacingEditFieldLabel.Enable = 'off';
            app.WindowSpacingEditFieldLabel.Text = 'Window Spacing';
            app.RefSelPIVWdwSpcEditField = uieditfield(app.ReferenceSelectionButtonGroup, 'text');
            app.RefSelPIVWdwSpcEditField.Enable = 'off';
            app.RefSelPIVWdwSpcEditField.Value = '128';
            app.referenceframesEditFieldLabel = uilabel(app.ReferenceSelectionButtonGroup);
            app.referenceframesEditFieldLabel.Enable = 'off';
            app.referenceframesEditFieldLabel.Text = '# reference frames';
            app.RefSelPIVNrefEditField = uieditfield(app.ReferenceSelectionButtonGroup, 'text');
            app.RefSelPIVNrefEditField.Enable = 'off';
            app.RefSelPIVNrefEditField.Value = '2';
            app.RefSelPIVSkipCheckBox = uicheckbox(app.ReferenceSelectionButtonGroup);
            app.RefSelPIVSkipCheckBox.Enable = 'off';
            app.RefSelPIVSkipCheckBox.Text = 'Skip low motion frames';
            app.RefSelPIVSkipCheckBox.Value = true;
            app.referenceframesEditField_2Label = uilabel(app.ReferenceSelectionButtonGroup);
            app.referenceframesEditField_2Label.Text = '# reference frames';
            app.RefSelF2FNrefEditField = uieditfield(app.ReferenceSelectionButtonGroup, 'text');
            app.RefSelF2FNrefEditField.Value = '2';
            app.RefSelF2FSkipCheckBox = uicheckbox(app.ReferenceSelectionButtonGroup);
            app.RefSelF2FSkipCheckBox.Text = 'Skip low motion frames';
            app.RefSelF2FSkipCheckBox.Value = true;
            app.RefSelManualRefEditField = uieditfield(app.ReferenceSelectionButtonGroup, 'text');
            app.RefSelManualRefEditField.Enable = 'off';
            app.RefSelManualBrowseButton = uibutton(app.ReferenceSelectionButtonGroup, 'push');
            app.RefSelManualBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @RefSelManualBrowseButtonPushed, true);
            app.RefSelManualBrowseButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder-solid.svg');
            app.RefSelManualBrowseButton.Enable = 'off';
            app.RefSelManualBrowseButton.Text = 'Load';
            app.RefSelManualNewFileButton = uibutton(app.ReferenceSelectionButtonGroup, 'push');
            app.RefSelManualNewFileButton.ButtonPushedFcn = createCallbackFcn(app, @RefSelManualNewFileButtonPushed, true);
            app.RefSelManualNewFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'file-solid.svg');
            app.RefSelManualNewFileButton.Enable = 'off';
            app.RefSelManualNewFileButton.Text = 'New';
            app.RefSelDifferentFolder = uiradiobutton(app.ReferenceSelectionButtonGroup);
            app.RefSelDifferentFolder.Text = 'The reference frames are in a separate folder';
            app.RefSelFolderDropDown = uidropdown(app.ReferenceSelectionButtonGroup);
            app.RefSelFolderDropDown.Enable = 'off';
            app.DeformationMeasurementsPIVPanel = uipanel(app.PIVConfigurationTab);
            app.DeformationMeasurementsPIVPanel.Title = 'Deformation Measurements (PIV)';
            app.UITablePIV = uitable(app.DeformationMeasurementsPIVPanel);
            app.UITablePIV.ColumnName = {'Pass'; 'Window Size'; 'Window Spacing'};
            app.UITablePIV.RowName = {};
            app.UITablePIV.ColumnEditable = [false true true];
            app.NextButton_2 = uibutton(app.PIVConfigurationTab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.BackButton = uibutton(app.PIVConfigurationTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.AddPassButton = uibutton(app.PIVConfigurationTab, 'push');
            app.AddPassButton.ButtonPushedFcn = createCallbackFcn(app, @AddPassButtonPushed, true);
            app.AddPassButton.Icon = fullfile(pathToMLAPP, 'icons', 'plus-solid.svg');
            app.AddPassButton.Tooltip = {'Add a PIV step'};
            app.AddPassButton.Text = '';
            app.RemovePassButton = uibutton(app.PIVConfigurationTab, 'push');
            app.RemovePassButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePassButtonPushed, true);
            app.RemovePassButton.Icon = fullfile(pathToMLAPP, 'icons', 'minus-solid.svg');
            app.RemovePassButton.Tooltip = {'Remove last PIV step'};
            app.RemovePassButton.Text = '';
            app.OtherPanel = uipanel(app.PIVConfigurationTab);
            app.OtherPanel.Title = 'Other';
            app.SubpixelInterpolationMethodDropDown_2Label = uilabel(app.OtherPanel);
            app.SubpixelInterpolationMethodDropDown_2Label.Text = 'Subpixel Interpolation Method';
            app.InterpMethodDropDown = uidropdown(app.OtherPanel);
            app.InterpMethodDropDown.Items = {'gaussian', 'polynomial'};
            app.InterpMethodDropDown.Value = 'gaussian';
            app.AcquisitionRateframespersecondLabel = uilabel(app.OtherPanel);
            app.AcquisitionRateframespersecondLabel.Text = 'Acquisition Rate (frames per second)';
            app.AcquisitionRate = uieditfield(app.OtherPanel, 'text');
            app.CorrectWholeImageDrift = uicheckbox(app.OtherPanel);
            app.CorrectWholeImageDrift.ValueChangedFcn = createCallbackFcn(app, @CorrectWholeImageDriftValueChanged, true);
            app.CorrectWholeImageDrift.Text = 'Correct Image Drift';
            app.UseFeatureTrackingCheckBox = uicheckbox(app.OtherPanel);
            app.UseFeatureTrackingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFeatureTrackingCheckBoxValueChanged, true);
            app.UseFeatureTrackingCheckBox.Enable = 'off';
            app.UseFeatureTrackingCheckBox.Text = 'Use Feature Tracking';
            app.PIVexecutionButtonGroup = uibuttongroup(app.PIVConfigurationTab);
            app.PIVexecutionButtonGroup.Title = 'PIV execution';
            app.FreshRunButton = uiradiobutton(app.PIVexecutionButtonGroup);
            app.FreshRunButton.Text = 'Fresh run';
            app.FreshRunButton.Value = true;
            app.augumentRunButton = uiradiobutton(app.PIVexecutionButtonGroup);
            app.augumentRunButton.Enable = 'off';
            app.augumentRunButton.Text = 'Augument existing results';
            app.TFMandPerformanceTab = uitab(app.TabGroup);
            app.TFMandPerformanceTab.Title = '3. TFM and Performance';
            app.ContractileForcemoduleTFMPanel = uipanel(app.TFMandPerformanceTab);
            app.ContractileForcemoduleTFMPanel.Title = 'Contractile Force module (TFM)';
            app.CalibrationFactormpixelLabel = uilabel(app.ContractileForcemoduleTFMPanel);
            app.CalibrationFactormpixelLabel.Text = 'Calibration Factor (μm/pixel)';
            app.CalibrationFactor = uieditfield(app.ContractileForcemoduleTFMPanel, 'text');
            app.CalibrationFactor.Enable = 'off';
            app.GelThicknessmLabel_2 = uilabel(app.ContractileForcemoduleTFMPanel);
            app.GelThicknessmLabel_2.Text = 'Gel Thickness (μm)';
            app.GelThickness = uieditfield(app.ContractileForcemoduleTFMPanel, 'text');
            app.GelThickness.Enable = 'off';
            app.GelYoungsModuluskPaLabel = uilabel(app.ContractileForcemoduleTFMPanel);
            app.GelYoungsModuluskPaLabel.Text = 'Gel Young''s Modulus (kPa)';
            app.GelStiffness = uieditfield(app.ContractileForcemoduleTFMPanel, 'text');
            app.GelStiffness.Enable = 'off';
            app.DoTFM = uicheckbox(app.ContractileForcemoduleTFMPanel);
            app.DoTFM.ValueChangedFcn = createCallbackFcn(app, @DoTFMValueChanged, true);
            app.DoTFM.Text = 'Enable Traction Force Microscopy';
            app.PerformanceParametersPanel = uipanel(app.TFMandPerformanceTab);
            app.PerformanceParametersPanel.Title = 'Performance Parameters';
            app.GPUAccelerationDropDownLabel = uilabel(app.PerformanceParametersPanel);
            app.GPUAccelerationDropDownLabel.Text = 'GPU Acceleration';
            app.GPUAccelerationDropDown = uidropdown(app.PerformanceParametersPanel);
            app.GPUAccelerationDropDown.Items = {'enabled', 'disabled'};
            app.GPUAccelerationDropDown.ValueChangedFcn = createCallbackFcn(app, @GPUAccelerationDropDownValueChanged, true);
            app.GPUAccelerationDropDown.Value = 'disabled';
            app.MaxGPUMemGBLabel = uilabel(app.PerformanceParametersPanel);
            app.MaxGPUMemGBLabel.Text = 'Max GPU Mem. (GB) ';
            app.MaxGPUMemEditField = uieditfield(app.PerformanceParametersPanel, 'text');
            app.MaxGPUMemEditField.Enable = 'off';
            app.MaxGPUMemEditField.Value = '1';
            app.MaxCPUMem = uieditfield(app.PerformanceParametersPanel, 'text');
            app.MaxCPUMem.Value = '5';
            app.MaxRAMGBLabel = uilabel(app.PerformanceParametersPanel);
            app.MaxRAMGBLabel.Text = 'Max RAM (GB)';
            app.NextButton_3 = uibutton(app.TFMandPerformanceTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.BackButton_2 = uibutton(app.TFMandPerformanceTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.SingleCellAnalysisButtonGroup = uibuttongroup(app.TFMandPerformanceTab);
            app.SingleCellAnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SingleCellAnalysisButtonGroupSelectionChanged, true);
            app.SingleCellAnalysisButtonGroup.Title = 'Single Cell Analysis';
            app.MaskFromVideo = uiradiobutton(app.SingleCellAnalysisButtonGroup);
            app.MaskFromVideo.Enable = 'off';
            app.MaskFromVideo.Text = 'Use an image from the video to obtain cell masks (Cellpose 2)';
            app.MaskFromFolder = uiradiobutton(app.SingleCellAnalysisButtonGroup);
            app.MaskFromFolder.Enable = 'off';
            app.MaskFromFolder.Text = 'Images for cell masks are in a separate folder';
            app.MaskFromFolder.Value = true;
            app.MaskFolderDropDown = uidropdown(app.SingleCellAnalysisButtonGroup);
            app.MaskFolderDropDown.Enable = 'off';
            app.EnableSingleCell = uicheckbox(app.SingleCellAnalysisButtonGroup);
            app.EnableSingleCell.ValueChangedFcn = createCallbackFcn(app, @EnableSingleCellValueChanged, true);
            app.EnableSingleCell.Text = 'Enable Single Cell Analysis';
            app.CellPoseModelDropDownLabel = uilabel(app.SingleCellAnalysisButtonGroup);
            app.CellPoseModelDropDownLabel.Text = 'CellPoseModel';
            app.CellPoseModelDropDown = uidropdown(app.SingleCellAnalysisButtonGroup);
            app.CellPoseModelDropDown.Enable = 'off';
            app.ElastographySerranoetal2023 = uipanel(app.TFMandPerformanceTab);
            app.ElastographySerranoetal2023.Title = 'Stiffness module';
            app.DoElasto = uicheckbox(app.ElastographySerranoetal2023);
            app.DoElasto.Text = 'Enable stiffness module';
            app.PIVPassToUse = uipanel(app.TFMandPerformanceTab);
            app.PIVPassToUse.Title = 'Perform analysis on ....';
            app.PIVPassDropDownLabel = uilabel(app.PIVPassToUse);
            app.PIVPassDropDownLabel.Text = 'PIV Pass';
            app.PIVPassDropDown = uidropdown(app.PIVPassToUse);
            app.RunAnalysisTab = uitab(app.TabGroup);
            app.RunAnalysisTab.Title = '4. Run Analysis';
            app.UITableStatus = uitable(app.RunAnalysisTab);
            app.UITableStatus.ColumnName = {'Location'; 'Alias'; 'Status'};
            app.UITableStatus.RowName = {};
            app.StartAnalysisButton = uibutton(app.RunAnalysisTab, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonPushed, true);
            app.StartAnalysisButton.Icon = fullfile(pathToMLAPP, 'icons', 'play-solid.svg');
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.BackButton_3 = uibutton(app.RunAnalysisTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.QuitAnalysisButton = uibutton(app.RunAnalysisTab, 'push');
            app.QuitAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @QuitAnalysisButtonPushed, true);
            app.QuitAnalysisButton.Icon = fullfile(pathToMLAPP, 'icons', 'circle-xmark-solid.svg');
            app.QuitAnalysisButton.Enable = 'off';
            app.QuitAnalysisButton.Text = 'Quit Analysis';
            app.UITableProgress = uitable(app.RunAnalysisTab);
            app.UITableProgress.ColumnName = {'No.'; 'Location'; 'Alias'; 'Status'};
            app.UITableProgress.RowName = {};
            app.UITableProgress.ColumnEditable = [false false false false];
            app.ProgressLabel = uilabel(app.RunAnalysisTab);
            app.ProgressLabel.Text = 'Progress';
            app.ETAEditFieldLabel = uilabel(app.RunAnalysisTab);
            app.ETAEditFieldLabel.Text = 'ETA';
            app.ETA = uieditfield(app.RunAnalysisTab, 'text');
            app.hrsLabel = uilabel(app.RunAnalysisTab);
            app.hrsLabel.Text = 'hrs';
            app.WholeFieldAnalysisLabel = uilabel(app.RunAnalysisTab);
            app.WholeFieldAnalysisLabel.Text = 'Whole Field Analysis';
            app.ModuleDropDownLabel = uilabel(app.RunAnalysisTab);
            app.ModuleDropDownLabel.Text = 'Module';
            app.ModuleDropDown = uidropdown(app.RunAnalysisTab);
            app.PIVPassDropDown_2Label = uilabel(app.RunAnalysisTab);
            app.PIVPassDropDown_2Label.Text = 'PIV Pass';
            app.PIVPassDropDown_2 = uidropdown(app.RunAnalysisTab);
            app.MAESTRAContractilityAnalysisLabel = uilabel(app.UIFigure);
            app.MAESTRAContractilityAnalysisLabel.Text = 'MAESTRA ( Contractility Analysis )';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContracilityAnalysisBeta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end